classdef LeG_GrayWhiteMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DefaultTypeDropDown       matlab.ui.control.DropDown
        DefaultTypeDropDownLabel  matlab.ui.control.Label
        ThresholdEditField        matlab.ui.control.EditField
        ThresholdEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        LeGUI 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, varargin)
            if nargin>1
                app.LeGUI = varargin{1}; %LeGUI object
            end  

            if ~isempty(app.LeGUI)
                app.ThresholdEditField.Value = num2str(app.LeGUI.graywhite.threshold);
                app.DefaultTypeDropDown.Value = app.LeGUI.graywhite.defaulttype; %'Gray' or 'White'
            end
            
            app.UIFigure.Name = 'Gray White Parameters';

            %place figure at center of screen
            scrsize = get(0,'screensize');
            winpos = app.UIFigure.Position;
            app.UIFigure.Position = [(scrsize(3)-winpos(3))/2,(scrsize(4)-winpos(4))/2,winpos(3),winpos(4)];
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app)
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldFcn(app, event)
            app.LeGUI.graywhite.threshold = str2double(app.ThresholdEditField.Value);
        end

        % Value changed function: DefaultTypeDropDown
        function DefaultTypeDropDownValueChanged(app, event)
            app.LeGUI.graywhite.defaulttype = app.DefaultTypeDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldFcn, true);
            app.ThresholdEditField.Tooltip = {'Threshold to determine tissue type using average voxel probabilities in electrode sphere for gray or white segmentations. If both gray and white are below this threshold, then tissue type is ''Unknown''.'};
            app.ThresholdEditField.Value = '0.1';
            app.DefaultTypeDropDownLabel = uilabel(app.UIFigure);
            app.DefaultTypeDropDownLabel.Enable = 'off';
            app.DefaultTypeDropDownLabel.Text = 'Default Type';
            app.DefaultTypeDropDown = uidropdown(app.UIFigure);
            app.DefaultTypeDropDown.Items = {'Gray', 'White'};
            app.DefaultTypeDropDown.ItemsData = {'Gray', 'White'};
            app.DefaultTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DefaultTypeDropDownValueChanged, true);
            app.DefaultTypeDropDown.Enable = 'off';
            app.DefaultTypeDropDown.Tooltip = {'Disabled in v2.0 or greater. If the gray and white probability distributions are not different based on a rank sum test, then the type is set to this value.'};
            app.DefaultTypeDropDown.Value = 'Gray';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeG_GrayWhiteMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end